classdef EasyEdit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        ApplyChangesButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ImportandExportTab              matlab.ui.container.Tab
        pngFileExportNameEditField      matlab.ui.control.EditField
        pngFileExportNameEditFieldLabel  matlab.ui.control.Label
        ExportTheAboveImageAsButton     matlab.ui.control.Button
        ImportTheBelowpngImageButton    matlab.ui.control.Button
        pngFileNameEditField            matlab.ui.control.EditField
        pngFileNameEditFieldLabel       matlab.ui.control.Label
        TranslationsTab                 matlab.ui.container.Tab
        InvertOverYAxisButton           matlab.ui.control.Button
        Rotate90DegreesButton           matlab.ui.control.Button
        ColorTab                        matlab.ui.container.Tab
        EntropyButton                   matlab.ui.control.Button
        SepiaRGBONLYButton              matlab.ui.control.Button
        InvertColorsButton              matlab.ui.control.Button
        ConvertToGrayscaleButton        matlab.ui.control.Button
        SlidersTab                      matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        SaturationSlider                matlab.ui.control.Slider
        SaturationSliderLabel           matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        BrightnessSliderLabel           matlab.ui.control.Label
        Sliders2Tab                     matlab.ui.container.Tab
        SharpenSlider                   matlab.ui.control.Slider
        SharpenSliderLabel              matlab.ui.control.Label
        SPSlider_2                      matlab.ui.control.Slider
        SPSlider_2Label                 matlab.ui.control.Label
        SpeckleSlider                   matlab.ui.control.Slider
        SpeckleSliderLabel              matlab.ui.control.Label
        BlurSlider                      matlab.ui.control.Slider
        BlurSliderLabel                 matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        AdaptiveThresholdingYMMVButton  matlab.ui.control.Button
        x3Mask111191111Button           matlab.ui.control.Button
        HistogramEqualizationButton     matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        inputFileName % Description
        pictureDataOld
        pictureDataNew
        angle=1;
        laplacian = [-1,-1,-1;-1,9,-1;-1,-1,-1];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: pngFileNameEditField
        function pngFileNameEditFieldValueChanged(app, event)
            value = app.pngFileNameEditField.Value;
            app.inputFileName = value;
        end

        % Button pushed function: ImportTheBelowpngImageButton
        function ImportTheBelowpngImageButtonPushed(app, event)
            app.pictureDataOld = imread(app.inputFileName);
            app.pictureDataNew = app.pictureDataOld;
            imshow(app.pictureDataOld, 'Parent',app.UIAxes);
        end

        % Value changed function: pngFileExportNameEditField
        function pngFileExportNameEditFieldValueChanged(app, event)
            exportName = app.pngFileExportNameEditField.Value;
            imwrite(app.pictureDataOld, exportName,"png");
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            app.pictureDataOld = app.pictureDataNew;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.pictureDataNew = app.pictureDataOld;
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: InvertOverYAxisButton
        function InvertOverYAxisButtonPushed(app, event)
          j = flip(app.pictureDataOld,2);
          app.pictureDataNew = j;
          imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: Rotate90DegreesButton
        function Rotate90DegreesButtonPushed(app, event)
            j = imrotate(app.pictureDataOld, 90*app.angle,"nearest","loose");
            app.angle = app.angle+1;
            app.pictureDataNew = j;
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: ConvertToGrayscaleButton
        function ConvertToGrayscaleButtonPushed(app, event)
            j = im2gray(app.pictureDataOld);
            app.pictureDataNew = j;
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: InvertColorsButton
        function InvertColorsButtonPushed(app, event)
           app.pictureDataNew = 255 - app.pictureDataOld;
           imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: SepiaRGBONLYButton
        function SepiaRGBONLYButtonPushed(app, event)
            for i = app.pictureDataOld(:,:,1)
                app.pictureDataNew(:,:,1) = app.pictureDataOld(:,:,1)*.393 + app.pictureDataOld(:,:,2) * .769 + app.pictureDataOld(:,:,3) * .189;
            end
            for i = app.pictureDataOld(:,:,2)
                app.pictureDataNew(:,:,2) = app.pictureDataOld(:,:,1)*.349 + app.pictureDataOld(:,:,2) * .686 + app.pictureDataOld(:,:,3) * .168;
            end
            for i = app.pictureDataOld(:,:,3)
                app.pictureDataNew(:,:,3) = app.pictureDataOld(:,:,1)*.272 + app.pictureDataOld(:,:,2) * .534 + app.pictureDataOld(:,:,3) * .131;
            end
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: EntropyButton
        function EntropyButtonPushed(app, event)
            j = entropyfilt(app.pictureDataOld);
            app.pictureDataNew = j;
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: BrightnessSlider
        function BrightnessSliderValueChanged(app, event)
            value = app.BrightnessSlider.Value;
            value = (value - 50)*5.1;
            if (app.pictureDataOld + value) > 255
                app.pictureDataNew = 255;
            elseif (app.pictureDataOld + value) < 0
                app.pictureDataNew = 0;
            else
                app.pictureDataNew = app.pictureDataOld + value;
            end
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: ContrastSlider
        function ContrastSliderValueChanged(app, event)
            value = (100- app.ContrastSlider.Value)/200;
            if value == 0
                value = value-.0005;
            end
            low = .5-value;
            high  =.5+value;
            app.pictureDataNew = imadjust(app.pictureDataOld,[low,high],[]);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: SaturationSlider
        function SaturationSliderValueChanged(app, event)
            value = app.SaturationSlider.Value;
            change = value -50;
            hsv = rgb2hsv(app.pictureDataOld);
            hsv(:,:,2) = hsv(:,:,2) + (change/100);
            hsv(hsv>1)=1;
            app.pictureDataNew = hsv2rgb(hsv);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: BlurSlider
        function BlurSliderValueChanged(app, event)
            value = (10*app.BlurSlider.Value)/100;
            if value ==0
                imshow(app.pictureDataNew, 'Parent',app.UIAxes);
                return
            end
            app.pictureDataNew = imgaussfilt(app.pictureDataOld,value);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: SpeckleSlider
        function SpeckleSliderValueChanged(app, event)
            value = app.SpeckleSlider.Value/500;
            if value ==0
                imshow(app.pictureDataOld, 'Parent',app.UIAxes);
                return
            end
            app.pictureDataNew=imnoise(app.pictureDataOld,"speckle",value);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: SharpenSlider
        function SharpenSliderValueChanged(app, event)
            value = app.SharpenSlider.Value;
            app.pictureDataNew = imsharpen(app.pictureDataOld,"amount",value);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: HistogramEqualizationButton
        function HistogramEqualizationButtonPushed(app, event)
            app.pictureDataNew = im2gray(app.pictureDataOld);
            app.pictureDataNew = histeq(app.pictureDataNew);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: x3Mask111191111Button
        function x3Mask111191111ButtonPushed(app, event)
            app.pictureDataNew = im2gray(app.pictureDataOld);
            app.pictureDataNew = conv2(app.pictureDataNew,app.laplacian);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Button pushed function: AdaptiveThresholdingYMMVButton
        function AdaptiveThresholdingYMMVButtonPushed(app, event)
            pic = im2gray(app.pictureDataOld);
            T = adaptthresh(pic);
            pic(pic>T) = 255;
            pic(pic<=T) = 0;
            app.pictureDataNew = pic;
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end

        % Value changed function: SPSlider_2
        function SPSlider_2ValueChanged(app, event)
            value = app.SPSlider_2.Value/500;
            if value ==0
                imshow(app.pictureDataOld, 'Parent',app.UIAxes);
                return
            end
            app.pictureDataNew=imnoise(app.pictureDataOld,"salt & pepper",value);
            imshow(app.pictureDataNew, 'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, {'Current State of The Image:'; ''})
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ImportandExportTab = uitab(app.TabGroup);
            app.ImportandExportTab.Title = 'Import and Export';
            app.pngFileNameEditFieldLabel = uilabel(app.ImportandExportTab);
            app.pngFileNameEditFieldLabel.Text = '.png File Name';
            app.pngFileNameEditField = uieditfield(app.ImportandExportTab, 'text');
            app.pngFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @pngFileNameEditFieldValueChanged, true);
            app.ImportTheBelowpngImageButton = uibutton(app.ImportandExportTab, 'push');
            app.ImportTheBelowpngImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportTheBelowpngImageButtonPushed, true);
            app.ImportTheBelowpngImageButton.Text = 'Import The Below .png Image';
            app.ExportTheAboveImageAsButton = uibutton(app.ImportandExportTab, 'push');
            app.ExportTheAboveImageAsButton.Text = 'Export The Above Image As:';
            app.pngFileExportNameEditFieldLabel = uilabel(app.ImportandExportTab);
            app.pngFileExportNameEditFieldLabel.Text = '.png File Export Name';
            app.pngFileExportNameEditField = uieditfield(app.ImportandExportTab, 'text');
            app.pngFileExportNameEditField.ValueChangedFcn = createCallbackFcn(app, @pngFileExportNameEditFieldValueChanged, true);
            app.TranslationsTab = uitab(app.TabGroup);
            app.TranslationsTab.Title = 'Translations';
            app.Rotate90DegreesButton = uibutton(app.TranslationsTab, 'push');
            app.Rotate90DegreesButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate90DegreesButtonPushed, true);
            app.Rotate90DegreesButton.Text = 'Rotate 90 Degrees';
            app.InvertOverYAxisButton = uibutton(app.TranslationsTab, 'push');
            app.InvertOverYAxisButton.ButtonPushedFcn = createCallbackFcn(app, @InvertOverYAxisButtonPushed, true);
            app.InvertOverYAxisButton.Text = 'Invert Over Y Axis';
            app.ColorTab = uitab(app.TabGroup);
            app.ColorTab.Title = 'Color';
            app.ConvertToGrayscaleButton = uibutton(app.ColorTab, 'push');
            app.ConvertToGrayscaleButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToGrayscaleButtonPushed, true);
            app.ConvertToGrayscaleButton.Text = 'Convert To Grayscale';
            app.InvertColorsButton = uibutton(app.ColorTab, 'push');
            app.InvertColorsButton.ButtonPushedFcn = createCallbackFcn(app, @InvertColorsButtonPushed, true);
            app.InvertColorsButton.Text = 'Invert Colors';
            app.SepiaRGBONLYButton = uibutton(app.ColorTab, 'push');
            app.SepiaRGBONLYButton.ButtonPushedFcn = createCallbackFcn(app, @SepiaRGBONLYButtonPushed, true);
            app.SepiaRGBONLYButton.Text = 'Sepia (RGB ONLY)';
            app.EntropyButton = uibutton(app.ColorTab, 'push');
            app.EntropyButton.ButtonPushedFcn = createCallbackFcn(app, @EntropyButtonPushed, true);
            app.EntropyButton.Text = 'Entropy';
            app.SlidersTab = uitab(app.TabGroup);
            app.SlidersTab.Title = 'Sliders';
            app.BrightnessSliderLabel = uilabel(app.SlidersTab);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.SlidersTab);
            app.BrightnessSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessSliderValueChanged, true);
            app.BrightnessSlider.Value = 50;
            app.ContrastSliderLabel = uilabel(app.SlidersTab);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.SlidersTab);
            app.ContrastSlider.Limits = [0 99];
            app.ContrastSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastSliderValueChanged, true);
            app.ContrastSlider.Value = 50;
            app.SaturationSliderLabel = uilabel(app.SlidersTab);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.SlidersTab);
            app.SaturationSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationSliderValueChanged, true);
            app.SaturationSlider.Value = 50;
            app.TextArea = uitextarea(app.SlidersTab);
            app.TextArea.Value = {'Saturation Does Not Work for B+W Images!'};
            app.Sliders2Tab = uitab(app.TabGroup);
            app.Sliders2Tab.Title = 'Sliders 2';
            app.BlurSliderLabel = uilabel(app.Sliders2Tab);
            app.BlurSliderLabel.Text = 'Blur';
            app.BlurSlider = uislider(app.Sliders2Tab);
            app.BlurSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.BlurSlider.ValueChangedFcn = createCallbackFcn(app, @BlurSliderValueChanged, true);
            app.SpeckleSliderLabel = uilabel(app.Sliders2Tab);
            app.SpeckleSliderLabel.Text = 'Speckle';
            app.SpeckleSlider = uislider(app.Sliders2Tab);
            app.SpeckleSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SpeckleSlider.ValueChangedFcn = createCallbackFcn(app, @SpeckleSliderValueChanged, true);
            app.SPSlider_2Label = uilabel(app.Sliders2Tab);
            app.SPSlider_2Label.Text = 'S+P';
            app.SPSlider_2 = uislider(app.Sliders2Tab);
            app.SPSlider_2.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SPSlider_2.ValueChangedFcn = createCallbackFcn(app, @SPSlider_2ValueChanged, true);
            app.SharpenSliderLabel = uilabel(app.Sliders2Tab);
            app.SharpenSliderLabel.Text = 'Sharpen';
            app.SharpenSlider = uislider(app.Sliders2Tab);
            app.SharpenSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.SharpenSlider.ValueChangedFcn = createCallbackFcn(app, @SharpenSliderValueChanged, true);
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.HistogramEqualizationButton = uibutton(app.AdvancedTab, 'push');
            app.HistogramEqualizationButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramEqualizationButtonPushed, true);
            app.HistogramEqualizationButton.Text = 'Histogram Equalization';
            app.x3Mask111191111Button = uibutton(app.AdvancedTab, 'push');
            app.x3Mask111191111Button.ButtonPushedFcn = createCallbackFcn(app, @x3Mask111191111ButtonPushed, true);
            app.x3Mask111191111Button.Text = '3x3 Mask: { [-1,-1,-1], [-1,9,-1], [-1,-1,-1] }';
            app.AdaptiveThresholdingYMMVButton = uibutton(app.AdvancedTab, 'push');
            app.AdaptiveThresholdingYMMVButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptiveThresholdingYMMVButtonPushed, true);
            app.AdaptiveThresholdingYMMVButton.Text = 'Adaptive Thresholding (YMMV)';
            app.ApplyChangesButton = uibutton(app.UIFigure, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EasyEdit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end